classdef robot_ABB_IRB_2400 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        ExitButton           matlab.ui.control.Button
        RollEditField        matlab.ui.control.NumericEditField
        RollEditFieldLabel   matlab.ui.control.Label
        PitchEditField       matlab.ui.control.NumericEditField
        PitchEditFieldLabel  matlab.ui.control.Label
        YawEditField         matlab.ui.control.NumericEditField
        YawEditFieldLabel    matlab.ui.control.Label
        ZmmEditField         matlab.ui.control.NumericEditField
        ZmmEditFieldLabel    matlab.ui.control.Label
        YmmEditField         matlab.ui.control.NumericEditField
        YmmEditFieldLabel    matlab.ui.control.Label
        XmmEditField         matlab.ui.control.NumericEditField
        XmmEditFieldLabel    matlab.ui.control.Label
        Q6SliderLabel        matlab.ui.control.Label
        Q6Slider             matlab.ui.control.Slider
        Q6EditField          matlab.ui.control.NumericEditField
        Q6EditFieldLabel     matlab.ui.control.Label
        Q5SliderLabel        matlab.ui.control.Label
        Q5Slider             matlab.ui.control.Slider
        Q5EditField          matlab.ui.control.NumericEditField
        Q5EditFieldLabel     matlab.ui.control.Label
        Q4SliderLabel        matlab.ui.control.Label
        Q4Slider             matlab.ui.control.Slider
        Q4EditField          matlab.ui.control.NumericEditField
        Q4EditFieldLabel     matlab.ui.control.Label
        Q3SliderLabel        matlab.ui.control.Label
        Q3Slider             matlab.ui.control.Slider
        Q3EditField          matlab.ui.control.NumericEditField
        Q3EditFieldLabel     matlab.ui.control.Label
        Q2SliderLabel        matlab.ui.control.Label
        Q2Slider             matlab.ui.control.Slider
        Q2EditField          matlab.ui.control.NumericEditField
        Q2EditFieldLabel     matlab.ui.control.Label
        Q1EditField          matlab.ui.control.NumericEditField
        Q1EditFieldLabel     matlab.ui.control.Label
        InterfazGrficaRobotABBIRB2400Label  matlab.ui.control.Label
        Q1SliderLabel        matlab.ui.control.Label
        Q1Slider             matlab.ui.control.Slider
    end

    
    properties (Access = public)
        q = zeros(1,6); % Guarda valores articulares de q1 - q6
        L0 = 6.15*0.1;  
        L1 = 1.00*0.1;
        L2 = 7.05*0.1;
        L3 = 1.35*0.1;
        L4 = 7.55*0.1;
        %L5 = 0.85*0.1;
        L5 = 0;
        robotabb ; 
        %qlimits = [-180 180; -100 110; -60 65; -200 200; -120 120; -400 400];
        qlimits = [-180 180; -110 100; -65 60; -200 200; -120 120; -400 400]; %q2 y q3 son negativos por la convención que asignamos
        ws = [-2.5 2.5 -2.5 2.5 -1.6 3];
    end
    
    % métodos adiconales
    methods (Access = private)
        function results = round_vec(app,vec)
            % Pasa a cero los elementos cuya magnitud es menor a 0.00001
            aux = vec;
            for i = max(size(aux))
                if abs(aux(i)) < 0.00001
                    aux(i) = 0;
                end
            end
            results = aux;
        end
        
        function [] = update_limits(app)
            % Actualiza los límites de los campos numéricos y deslizadores
            % de q1-q6
            app.Q1Slider.Limits = app.qlimits(1,:);
            app.Q2Slider.Limits = app.qlimits(2,:);
            app.Q3Slider.Limits = app.qlimits(3,:);
            app.Q4Slider.Limits = app.qlimits(4,:);
            app.Q5Slider.Limits = app.qlimits(5,:);
            app.Q6Slider.Limits = app.qlimits(6,:);
            
            app.Q1EditField.Limits = app.qlimits(1,:);
            app.Q2EditField.Limits = app.qlimits(2,:);
            app.Q3EditField.Limits = app.qlimits(3,:);
            app.Q4EditField.Limits = app.qlimits(4,:);
            app.Q5EditField.Limits = app.qlimits(5,:);
            app.Q6EditField.Limits = app.qlimits(6,:);
        end
        
        function [] = update_pos(app)
            % Función actualiza los valores de la pose del efector final
            % x, y, z, roll, pitch y yaw
            T = app.robotabb.fkine(app.q);
            xyz = round(transl(T),3)*1000;
            rpy = round(rad2deg(tr2rpy(T)),4);
            app.XmmEditField.Value = xyz(1);
            app.YmmEditField.Value = xyz(2);
            app.ZmmEditField.Value = xyz(3);
            app.RollEditField.Value = double(rpy(1));
            app.PitchEditField.Value = double(rpy(2));
            app.YawEditField.Value = double(rpy(3));
        end
        
        function [] = place_graphics(app,fig)
            % Función que coloca de forma centrada la figura del robot
            % y de forma centrada el la GUI, siempre y cuando, la
            % resolución de la pantalla sea mayor a 1221x800 pixeles

            fig.Units = 'pixels';
            size_screen = get(0,'ScreenSize');%[left bottom width height]
            disp(size_screen)
            disp(app.UIFigure.Position)
            if size_screen(3)>1221 && size_screen(4) > 800
                api_pos = app.UIFigure.OuterPosition; %[left bottom width height]
                fig_pos = fig.OuterPosition;
                px_api = (size_screen(3)-800-api_pos(3))*0.5;
                py_api = (size_screen(4)-api_pos(4))*0.5;
                if px_api > 0 && py_api > 0
                    app.UIFigure.Position = [px_api py_api api_pos(3) api_pos(4)];
                end
                px_fig = px_api+api_pos(3);
                py_fig = (size_screen(4)-fig_pos(4))*0.5;
                fig.Position = [px_fig py_fig 800 600];
            else
                disp('Resolución pequeña')
                fig.Position = [400 0 800 600];
                app.UIFigure.Position = [0 0 421  574];
            end
            
            fig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Función de configruación de inicio, se establecen los límites
            % en los deslizadores, se crea el objeto del robot
            app.UIFigure.Visible = 'off';
            update_limits(app)
            
            L(1) = Link('revolute','alpha',     0,'a',     0,'d',app.L0,'offset',0,'modified');
            L(2) = Link('revolute','alpha',  pi/2,'a',app.L1,'d',     0,'offset',pi/2,'modified');
            L(3) = Link('revolute','alpha',     0,'a',app.L2,'d',     0,'offset',0,'modified');
            L(4) = Link('revolute','alpha',  pi/2,'a',app.L3,'d',app.L4,'offset',0,'modified');
            L(5) = Link('revolute','alpha', -pi/2,'a',     0,'d',     0,'offset',0,'modified');
            L(6) = Link('revolute','alpha',  pi/2,'a',     0,'d',app.L5,'offset',0,'modified');
            
            
            plot_options = {'workspace',app.ws,'scale',.5};%,'view',[30 30],'tilesize',2,'ortho', 'lightpos',[2 2 10] };
            app.robotabb = SerialLink(L,'name','ABB IRB2400','plotopt',plot_options);
            
            fig = figure('Name','ROBOT: ABB IRB 2400','NumberTitle','off', ...
                'Position',[200 0 800 600],'visible','off');
            
            app.robotabb.plot(app.q)
            hold on
            trplot(eye(4),'rgb','thick',2,'frame','O','length',0.3)
            view([30 30])
            update_pos(app)
            
            place_graphics(app,fig)
        end

        % Value changed function: Q1EditField
        function Q1EditFieldValueChanged(app, event)
            app.q(1) = deg2rad(app.Q1EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q2EditField
        function Q2EditFieldValueChanged(app, event)
            app.q(2) = deg2rad(app.Q2EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q3EditField
        function Q3EditFieldValueChanged(app, event)
            app.q(3) = deg2rad(app.Q3EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q4EditField
        function Q4EditFieldValueChanged(app, event)
            app.q(4) = deg2rad(app.Q4EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changed function: Q5EditField
        function Q5EditFieldValueChanged(app, event)
            app.q(5) = deg2rad(app.Q5EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Callback function
        function Q6EditFieldValueChanged(app, event)
            app.q(6) = deg2rad(app.Q6EditField.Value);
            update_pos(app)
            app.robotabb.plot(app.q);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            val = event.Value;
            app.q(1) = deg2rad(val);
            app.Q1EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            val = event.Value;
            app.q(2) = deg2rad(val);
            app.Q2EditField.Value = round(val,2);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            val = event.Value;
            app.q(3) = deg2rad(val);
            app.Q3EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            val = event.Value;
            app.q(4) = deg2rad(val);
            app.Q4EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            val = event.Value;
            app.q(5) = deg2rad(val);
            app.Q5EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            val = event.Value;
            app.q(6) = deg2rad(val);
            app.Q6EditField.Value = double(val);
            app.robotabb.plot(app.q);
            update_pos(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close all
            clc
            delete(app.UIFigure)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Deja los valores articulares en cero, actualizando los
            % valores de los campos de texto y los deslizadores a cero
            app.q = zeros(1,6);
            
            app.Q1EditField.Value = 0;
            app.Q2EditField.Value = 0;
            app.Q3EditField.Value = 0;
            app.Q4EditField.Value = 0;
            app.Q5EditField.Value = 0;
            app.Q6EditField.Value = 0;
            
            app.Q1Slider.Value = 0;
            app.Q2Slider.Value = 0;
            app.Q3Slider.Value = 0;
            app.Q4Slider.Value = 0;
            app.Q5Slider.Value = 0;
            app.Q6Slider.Value = 0;
            
            app.robotabb.plot(app.q);
            update_pos(app);
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            disp('calculo coordenada x')
            x = app.XmmEditField.Value;
            T = transl(0.001*x,0,0);
            disp(app.robotabb.ikine(T))
            disp(app.robotabb.ikcon(T))
            disp(app.robotabb.fkine(app.q))
            app.q = app.robotabb.ikcon(T);
            app.robotabb.plot(app.q);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q1Slider = uislider(app.UIFigure);
            app.Q1Slider.Limits = [-180 180];
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1SliderLabel = uilabel(app.UIFigure);
            app.Q1SliderLabel.Text = 'Q1  ';
            app.InterfazGrficaRobotABBIRB2400Label = uilabel(app.UIFigure);
            app.InterfazGrficaRobotABBIRB2400Label.Text = {'Interfaz Gráfica'; 'Robot ABB IRB 2400'};
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1EditField.ValueChangedFcn = createCallbackFcn(app, @Q1EditFieldValueChanged, true);
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditField.ValueChangedFcn = createCallbackFcn(app, @Q2EditFieldValueChanged, true);
            app.Q2Slider = uislider(app.UIFigure);
            app.Q2Slider.Limits = [-180 180];
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2SliderLabel = uilabel(app.UIFigure);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditField.ValueChangedFcn = createCallbackFcn(app, @Q3EditFieldValueChanged, true);
            app.Q3Slider = uislider(app.UIFigure);
            app.Q3Slider.Limits = [-180 180];
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3SliderLabel = uilabel(app.UIFigure);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditField.ValueChangedFcn = createCallbackFcn(app, @Q4EditFieldValueChanged, true);
            app.Q4Slider = uislider(app.UIFigure);
            app.Q4Slider.Limits = [-180 180];
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4SliderLabel = uilabel(app.UIFigure);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q5EditFieldLabel = uilabel(app.UIFigure);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditField.ValueChangedFcn = createCallbackFcn(app, @Q5EditFieldValueChanged, true);
            app.Q5Slider = uislider(app.UIFigure);
            app.Q5Slider.Limits = [-180 180];
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5SliderLabel = uilabel(app.UIFigure);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q6EditFieldLabel = uilabel(app.UIFigure);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q6Slider = uislider(app.UIFigure);
            app.Q6Slider.Limits = [-180 180];
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6SliderLabel = uilabel(app.UIFigure);
            app.Q6SliderLabel.Text = 'Q6';
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X [mm]';
            app.XmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y [mm]';
            app.YmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditField.Editable = 'off';
            app.ZmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmmEditFieldLabel.Text = 'Z [mm]';
            app.ZmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmmEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw [°]';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch [°]';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll [°]';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditField.Editable = 'off';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_ABB_IRB_2400

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end